classdef Paleochannel_en < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ProjectionofPaleochannelsPanel  matlab.ui.container.Panel
        numberTextArea                  matlab.ui.control.TextArea
        Label_3                         matlab.ui.control.Label
        DowncuttingTextArea_2           matlab.ui.control.TextArea
        DowncuttingTextArea_2Label      matlab.ui.control.Label
        integralconcavityTextArea       matlab.ui.control.TextArea
        integralconcavityTextAreaLabel  matlab.ui.control.Label
        DowncuttingTextArea             matlab.ui.control.TextArea
        Label_2                         matlab.ui.control.Label
        SAconcavityTextArea             matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        ResultsstorageButton            matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        ResultlocationButton            matlab.ui.control.Button
        DrawingprofilesButton           matlab.ui.control.Button
        TextArea_3                      matlab.ui.control.TextArea
        RiverInformationButton          matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        ReadingprofilesButton           matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        clarificationMenu               matlab.ui.container.Menu
        AppRiverProAnalysisMenu         matlab.ui.container.Menu
        RivernumberMenu                 matlab.ui.container.Menu
        NumberofstreamsrequiringpaleochannelanalysisMenu  matlab.ui.container.Menu
        ReadingriverprofilesMenu        matlab.ui.container.Menu
        RiverProAnalysisMenu            matlab.ui.container.Menu
        RiverinformationMenu            matlab.ui.container.Menu
        Pnt_analysistxtfilethatshouldbegeneratedforRiverProAnalysisMenu  matlab.ui.container.Menu
        DrawingprofilesMenu             matlab.ui.container.Menu
        LzchizMenu                      matlab.ui.container.Menu
        LzSAMenu                        matlab.ui.container.Menu
        chizchiMenu                     matlab.ui.container.Menu
        ResultlocationMenu              matlab.ui.container.Menu
        SelectthefileinwhichyouwanttostoretheresultsintxtformatMenu  matlab.ui.container.Menu
        ResultsstorageMenu              matlab.ui.container.Menu
        ReadthestoragelocationandstoretheresultinitMenu  matlab.ui.container.Menu
        SAMenu                          matlab.ui.container.Menu
    end

    
    properties (Access = private)
        out % Description
    end
    
    methods (Access = private)
        
        function [theta_mn, R,confidence_interval]= regression(app,computeZ,computeA,computeL)
            ss_total= sum((computeZ - mean(computeZ)).^2);  % 总平方和
            raw=length(computeZ);computechi=zeros(raw,1);
            R=zeros(1000,1);j=1;

         for theta=0:0.001:1
            for i=2:raw
               computechi(i)=(computeL(i)-computeL(i-1)).*computeA(i-1)^-theta+computechi(i-1);
            end
               fit = polyfit(computechi,computeZ,1);
                zz = fit(1,1)*computechi+fit(1,2);
                ss_residual = sum((computeZ- zz).^2); % 残差平方和
                R(j)=1 - ss_residual / ss_total;%相关系数R^2
                j=j+1;
         end
         [~, Xrow] = max(R(:));theta_mn=Xrow*0.001;
         % 计算R^2的标准误差
           dof = raw - 2; % 自由度
           mse = ss_residual / dof; % 均方误差
         r2_se = sqrt(2 * mse / ss_total);
         alpha = 0.10;% 构建90%置信区间
    z_critical = norminv(1 - alpha / 2, 0, 1); % 正态分布的临界值
    confidence_interval = [theta_mn - z_critical * r2_se, theta_mn + z_critical * r2_se];
        end
            
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadingprofilesButton
        function readtxt(app, event)
          % 让用户选择要读取的文件
           [filename, filepath] = uigetfile('*.txt', '选择要读取的文件');
    
          % 如果用户没有选择文件，取消操作
             if isequal(filename, 0)
                return;
             end
          % 构造完整的文件路径
            fullFilePath = fullfile(filepath, filename);
          % 在文本框中显示选取的文件地址
            app.TextArea_2.Value = fullFilePath;
        end

        % Button pushed function: RiverInformationButton
        function readtxt2(app, event)
              % 让用户选择要读取的文件
           [filename, filepath] = uigetfile('*.txt', '选择要读取的文件');
    
          % 如果用户没有选择文件，取消操作
             if isequal(filename, 0)
                return;
             end
          % 构造完整的文件路径
            fullFilePath = fullfile(filepath, filename);
          % 在文本框中显示选取的文件地址
            app.TextArea_3.Value = fullFilePath;
        end

        % Button pushed function: DrawingprofilesButton
        function plotting_profile(app, event)
          % 读取文件
       filePath = app.TextArea_2.Value{1};
       num_files = str2double(app.numberTextArea.Value{1});
       data = load(filePath);
       %读取文件信息，距离、高程、汇水面积
       L = data(:, 3);Z = data(:, 4);A = data(:, 6); 
       filePath2 = app.TextArea_3.Value{1};
       file2 = fopen(filePath2, 'r');
       %读取theta与ksn，支持多行
       i=1;
       while ~feof(file2)
       % 读取当前行
       line2 = fgetl(file2); row_data = strsplit(line2, '\t');  
       % 检查第一列是否与num_files匹配
           if str2double(row_data{1}) == num_files
              Lc(i)= str2double(row_data{4});
              theta1(i) = str2double(row_data{5}); ksn1(i) = str2double(row_data{6});
           i=i+1;
         end
       end
      fclose(file2);% 关闭文件

%获取分界点pnt的数量，然后分段
[~, pnt] = ismember(Lc, L); % 查找Lc中的值在L数据中的位置
pntsum = size(pnt, 2);
%输出量构建
app.out=zeros(1,7);
app.out(1,1)=num_files;
A=A(1:pnt(pntsum), :);  La=L(1:pnt(pntsum), :);Z=Z(1:pnt(pntsum), :);
%第一张图绘制
S1= exp(ksn1(pntsum)) * A.^-theta1(pntsum);
plot(app.UIAxes_5,La, Z, 'k'); hold(app.UIAxes_5, 'on');% 新图，横坐标为L，纵坐标为Z
if pntsum~=1
Z1=zeros(pnt(pntsum),1);   
Z1(pnt(pntsum-1))=Z(pnt(pntsum-1));%中间控制点
for j=pnt(pntsum-1)+1:pnt(pntsum)
    Z1(j)=S1(j)*(L(j)-L(j-1))+Z1(j-1);
end
for j=pnt(pntsum-1):-1:2
    Z1(j-1)=Z1(j)-S1(j)*(L(j+1)-L(j));
end
   plot(app.UIAxes_5,La, Z1, 'b');  hold(app.UIAxes_5, 'on');% S-A拟合
   text(app.UIAxes_5,La(1), Z1(1), sprintf(' Z（ logS-A ） = %.2f ', Z1(1)));
else
    %msgbox('无需古河道投影', '提示', 'warn');
end
app.SAconcavityTextArea.Value = sprintf('%.3f', theta1(end));
app.DowncuttingTextArea.Value = sprintf('%.2f', Z1(1)-Z(1));
%输出凹度、出山口高程、下切深度
app.out(1,2)=theta1(end);app.out(1,3)=Z(1);app.out(1,4)=Z1(1)-Z(1);
%%
%首先计算分段最佳凹度，之后计算得到最佳chi值
theta=zeros(pntsum,1);chi=zeros(pnt(pntsum),1);fit_R=zeros(pntsum,5);
fit_R(:, 1) = num_files;
if pntsum==1
compute_Z=Z(1:pnt(1),:);compute_A=A(1:pnt(1),:);compute_L=L(1:pnt(1),:); 
[theta(1), R,~]= regression(app,compute_Z,compute_A,compute_L);%计算最佳凹度
for j=2:pnt(pntsum)%计算新chi值
    chi(j)=(L(j)-L(j-1)).*A(j-1)^-theta(pntsum)+chi(j-1);
end
compute_chi=chi(1:pnt(1),:);
fit=polyfit(compute_chi,compute_Z,1);%拟合直线
fit_R(1,2)=1;fit_R(1,3)=theta(1);fit_R(1,4)=fit(1);fit_R(1,5)=fit(2);

%绘制图形
plot(app.UIAxes_6,chi, Z, 'r','linewidth',2.5); hold(app.UIAxes_6, 'on');% 右图，横坐标为chi，纵坐标为Z
x=0:0.1:chi(end);y=fit(1)*x+fit(2);
plot(app.UIAxes_6,x, y, 'b');hold(app.UIAxes_6, 'on');
text(app.UIAxes_6,mean(x),mean(y),  sprintf('Z = %.2f*x + %.2f,\n ，theta = %.3f', fit(1), fit(2),theta(pntsum)));
hold(app.UIAxes_6, 'on');

%小窗图
plot(app.UIAxes, 0:0.001:1, R,'k');hold(app.UIAxes, 'on');
plot(app.UIAxes, [theta(pntsum), theta(pntsum)],[0,1] ,'Color', [0.5 0.5 0.5], 'LineStyle','--');hold(app.UIAxes, 'on');
ylim(app.UIAxes,[min(R),max(R)]);
text(app.UIAxes, theta(pntsum),max(R),sprintf('m/n= %.3f\n ', theta(pntsum)));
hold(app.UIAxes, 'on');

else
%末段凹度计算
compute_Z=Z(pnt(pntsum-1):pnt(pntsum),:);
compute_A=A(pnt(pntsum-1):pnt(pntsum),:);
compute_L=L(pnt(pntsum-1):pnt(pntsum),:); 
[theta(pntsum),R,~]= regression(app,compute_Z,compute_A,compute_L);%计算最佳凹度
for j=2:pnt(pntsum)%计算新chi值
    chi(j)=(L(j)-L(j-1)).*A(j-1)^-theta(pntsum)+chi(j-1);
end
compute_chi=chi(pnt(pntsum-1):pnt(pntsum),:);
fit=polyfit(compute_chi,compute_Z,1);%拟合最上游直线
Z2=fit(1,2);
fit_R(pntsum,2)=pntsum;fit_R(pntsum,3)=theta(pntsum);fit_R(pntsum,4)=fit(1);fit_R(pntsum,5)=fit(2);

%绘制图形
plot(app.UIAxes_6,chi, Z, 'r','linewidth',2.5);hold(app.UIAxes_6, 'on');
x=0:0.1:chi(end);y=fit(1)*x+fit(2);
plot(app.UIAxes_6,x, y, 'b');hold(app.UIAxes_6, 'on');
text(app.UIAxes_6,mean(x),mean(y),  sprintf('Z = %.2f*x + %.2f,\n ，现theta = %.3f', fit(1), fit(2),theta(pntsum)));
hold(app.UIAxes_6, 'on');

%小窗图
plot(app.UIAxes, 0:0.001:1, R,'k');hold(app.UIAxes, 'on');
line(app.UIAxes,[theta(pntsum),theta(pntsum)], [0,1],'Color', [0.5 0.5 0.5], 'LineStyle','--');hold(app.UIAxes, 'on');
ylim(app.UIAxes,[min(R),max(R)]);
text(app.UIAxes,theta(pntsum),max(R),sprintf('m/n= %.3f\n ', theta(pntsum)));
hold(app.UIAxes, 'on');


%首段凹度计算
compute_Z=Z(1:pnt(1),:);compute_A=A(1:pnt(1),:);compute_L=L(1:pnt(1),:); 
[theta(1),~,~]= regression(app,compute_Z,compute_A,compute_L);%计算最佳凹度
compute_chi=chi(1:pnt(1),:);
fit=polyfit(compute_chi,compute_Z,1);%拟合最上游直线
fit_R(1,2)=1;fit_R(1,3)=theta(1);fit_R(1,4)=fit(1);fit_R(1,5)=fit(2);
x=0:0.01:compute_chi(end);y=fit(1)*x+fit(2);
plot(app.UIAxes_6,x, y, 'b');
hold(app.UIAxes_6, 'on');
%text(app.UIAxes_6,mean(x),mean(y),  sprintf('Z = %f*x + %f，theta = %.3f', fit(1), fit(2),theta(1)));
  if pntsum~=2%中间段凹度循环计算
    for K=1:pntsum-2
           compute_Z=Z(pnt(K):pnt(K+1),:);
           compute_A=A(pnt(K):pnt(K+1),:);
           compute_L=L(pnt(K):pnt(K+1),:); 
           [theta(K+1),~,~]= regression(app,compute_Z,compute_A,compute_L);
           compute_chi=chi(pnt(K):pnt(K+1),:);
           fit=polyfit(compute_chi,compute_Z,1);
           fit_R(K+1,2)=K+1;fit_R(K+1,3)=theta(K+1);fit_R(K+1,4)=fit(1);fit_R(K+1,5)=fit(2);
           x=compute_chi(1):compute_chi(end);y=fit(1)*x+fit(2);
           plot(app.UIAxes_6,x, y, 'b');hold(app.UIAxes_6, 'on');
           %text(app.UIAxes_6,mean(x),mean(y),  sprintf('Z = %f*x + %f,\n ，theta = %.3f', fit(1), fit(2),theta(K+1)));
    end
 end
end
app.integralconcavityTextArea.Value = sprintf('%.3f', theta(pntsum));
app.DowncuttingTextArea_2.Value = sprintf('%.2f', Z2-Z(1));
%输出凹度、出山口高程、下切深度
app.out(1,5)=theta(pntsum);app.out(1,6)=Z2;app.out(1,7)=Z2-Z(1);

        end

        % Button pushed function: ResultlocationButton
        function readtxt3(app, event)
           % 让用户选择要读取的文件
           [filename, filepath] = uigetfile('*.txt', '选择要读取的文件');
    
          % 如果用户没有选择文件，取消操作
             if isequal(filename, 0)
                return;
             end
          % 构造完整的文件路径
            fullFilePath = fullfile(filepath, filename);
          % 在文本框中显示选取的文件地址
            app.TextArea_4.Value = fullFilePath;  
        end

        % Button pushed function: ResultsstorageButton
        function ResultsstorageButtonPushed(app, event)
folderPath = app.TextArea_4.Value{1}; 
% 打开文件以追加数据
fid = fopen(folderPath, 'a');

% 检查文件是否成功打开
if fid == -1
    error('无法打开文件 %s 进行写入', folderPath);
end

% 格式化输出并将数据写入文件
fprintf(fid, '%.3f\t', app.out); % 使用制表符作为分隔符

% 写入换行符
fprintf(fid, '\n');

% 关闭文件
fclose(fid);
 msg = '运算已结束';title = '提示';
       msgbox(msg, title);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProjectionofPaleochannelsPanel = uipanel(app.UIFigure);
            app.ProjectionofPaleochannelsPanel.Title = 'Projection of Paleochannels';
            app.UIAxes_5 = uiaxes(app.ProjectionofPaleochannelsPanel);
            xlabel(app.UIAxes_5, 'Upstream distance (m)')
            ylabel(app.UIAxes_5, 'Elevation')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.ProjectionofPaleochannelsPanel);
            xlabel(app.UIAxes_6, 'Chi')
            ylabel(app.UIAxes_6, 'Elevation')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes = uiaxes(app.ProjectionofPaleochannelsPanel);
            xlabel(app.UIAxes, 'threa')
            ylabel(app.UIAxes, 'R')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = 1;
            app.ReadingprofilesButton = uibutton(app.ProjectionofPaleochannelsPanel, 'push');
            app.ReadingprofilesButton.ButtonPushedFcn = createCallbackFcn(app, @readtxt, true);
            app.ReadingprofilesButton.Text = 'Reading  profiles';
            app.TextArea_2 = uitextarea(app.ProjectionofPaleochannelsPanel);
            app.RiverInformationButton = uibutton(app.ProjectionofPaleochannelsPanel, 'push');
            app.RiverInformationButton.ButtonPushedFcn = createCallbackFcn(app, @readtxt2, true);
            app.RiverInformationButton.Text = 'River Information';
            app.TextArea_3 = uitextarea(app.ProjectionofPaleochannelsPanel);
            app.DrawingprofilesButton = uibutton(app.ProjectionofPaleochannelsPanel, 'push');
            app.DrawingprofilesButton.ButtonPushedFcn = createCallbackFcn(app, @plotting_profile, true);
            app.DrawingprofilesButton.Text = 'Drawing profiles';
            app.ResultlocationButton = uibutton(app.ProjectionofPaleochannelsPanel, 'push');
            app.ResultlocationButton.ButtonPushedFcn = createCallbackFcn(app, @readtxt3, true);
            app.ResultlocationButton.Text = 'Result location';
            app.TextArea_4 = uitextarea(app.ProjectionofPaleochannelsPanel);
            app.ResultsstorageButton = uibutton(app.ProjectionofPaleochannelsPanel, 'push');
            app.ResultsstorageButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsstorageButtonPushed, true);
            app.ResultsstorageButton.Text = 'Results storage';
            app.Hyperlink = uihyperlink(app.ProjectionofPaleochannelsPanel);
            app.Hyperlink.Text = 'Help';
            app.Label = uilabel(app.ProjectionofPaleochannelsPanel);
            app.Label.Text = 'S-A concavity  ';
            app.SAconcavityTextArea = uitextarea(app.ProjectionofPaleochannelsPanel);
            app.SAconcavityTextArea.Editable = 'off';
            app.Label_2 = uilabel(app.ProjectionofPaleochannelsPanel);
            app.Label_2.Text = 'Downcutting';
            app.DowncuttingTextArea = uitextarea(app.ProjectionofPaleochannelsPanel);
            app.DowncuttingTextArea.Editable = 'off';
            app.integralconcavityTextAreaLabel = uilabel(app.ProjectionofPaleochannelsPanel);
            app.integralconcavityTextAreaLabel.Text = 'integral concavity  ';
            app.integralconcavityTextArea = uitextarea(app.ProjectionofPaleochannelsPanel);
            app.integralconcavityTextArea.Editable = 'off';
            app.DowncuttingTextArea_2Label = uilabel(app.ProjectionofPaleochannelsPanel);
            app.DowncuttingTextArea_2Label.Text = 'Downcutting';
            app.DowncuttingTextArea_2 = uitextarea(app.ProjectionofPaleochannelsPanel);
            app.DowncuttingTextArea_2.Editable = 'off';
            app.Label_3 = uilabel(app.ProjectionofPaleochannelsPanel);
            app.Label_3.Text = 'number';
            app.numberTextArea = uitextarea(app.ProjectionofPaleochannelsPanel);
            app.numberTextArea.Editable = 'off';
            app.numberTextArea.Value = {'1'};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.clarificationMenu = uimenu(app.ContextMenu);
            app.clarificationMenu.Text = 'clarification';
            app.AppRiverProAnalysisMenu = uimenu(app.clarificationMenu);
            app.AppRiverProAnalysisMenu.Text = 'The App shall select the previously generated information after RiverProAnalysis has been successfully run.';
            app.RivernumberMenu = uimenu(app.ContextMenu);
            app.RivernumberMenu.Text = 'River number';
            app.NumberofstreamsrequiringpaleochannelanalysisMenu = uimenu(app.RivernumberMenu);
            app.NumberofstreamsrequiringpaleochannelanalysisMenu.Text = 'Number of streams requiring paleochannel analysis';
            app.ReadingriverprofilesMenu = uimenu(app.ContextMenu);
            app.ReadingriverprofilesMenu.Text = 'Reading river profiles';
            app.RiverProAnalysisMenu = uimenu(app.ReadingriverprofilesMenu);
            app.RiverProAnalysisMenu.Text = 'This should be the new profile generated by the RiverProAnalysis analysis';
            app.RiverinformationMenu = uimenu(app.ContextMenu);
            app.RiverinformationMenu.Text = 'River information';
            app.Pnt_analysistxtfilethatshouldbegeneratedforRiverProAnalysisMenu = uimenu(app.RiverinformationMenu);
            app.Pnt_analysistxtfilethatshouldbegeneratedforRiverProAnalysisMenu.Text = 'Pnt_analysis.txt file that should be generated for RiverProAnalysis';
            app.DrawingprofilesMenu = uimenu(app.ContextMenu);
            app.DrawingprofilesMenu.Text = 'Drawing profiles';
            app.LzchizMenu = uimenu(app.DrawingprofilesMenu);
            app.LzchizMenu.Text = 'By entering information in order to draw profiles and complete paleochannel projections in L-z, chi-z diagrams';
            app.LzSAMenu = uimenu(app.DrawingprofilesMenu);
            app.LzSAMenu.Text = 'The blue line in the L-z plot is the paleochannel projection completed using the S-A method of calculating the information';
            app.chizchiMenu = uimenu(app.DrawingprofilesMenu);
            app.chizchiMenu.Text = 'The red line in the chi-z plot is the new chi value recalculated using true concavity, and the blue line is the paleochannel projection line';
            app.ResultlocationMenu = uimenu(app.ContextMenu);
            app.ResultlocationMenu.Text = 'Result  location';
            app.SelectthefileinwhichyouwanttostoretheresultsintxtformatMenu = uimenu(app.ResultlocationMenu);
            app.SelectthefileinwhichyouwanttostoretheresultsintxtformatMenu.Text = 'Select the file in which you want to store the results in txt format.';
            app.ResultsstorageMenu = uimenu(app.ContextMenu);
            app.ResultsstorageMenu.Text = 'Results storage';
            app.ReadthestoragelocationandstoretheresultinitMenu = uimenu(app.ResultsstorageMenu);
            app.ReadthestoragelocationandstoretheresultinitMenu.Text = 'Read the storage location and store the result in it';
            app.SAMenu = uimenu(app.ResultsstorageMenu);
            app.SAMenu.Text = '结果包含：河流编号、S-A法凹度、出山口高程、裂点下切量、线性回归法凹度、出山口高程、裂点下切量';
            % Assign app.ContextMenu
            app.Hyperlink.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Paleochannel_en

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end