classdef RiverProAnalysis_en < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RiverelevationprofileanalysisPanel  matlab.ui.container.Panel
        Hyperlink                     matlab.ui.control.Hyperlink
        numberTextArea                matlab.ui.control.TextArea
        Label_9                       matlab.ui.control.Label
        StoringinformationButton      matlab.ui.control.Button
        CalculateButton               matlab.ui.control.Button
        TextArea_11                   matlab.ui.control.TextArea
        TextlocationButton            matlab.ui.control.Button
        shpfilelocationButton         matlab.ui.control.Button
        TextArea_10                   matlab.ui.control.TextArea
        knickpointTextArea            matlab.ui.control.TextArea
        Label_6                       matlab.ui.control.Label
        MethodsforfindingButtonGroup  matlab.ui.container.ButtonGroup
        Button_9                      matlab.ui.control.RadioButton
        logSAButton                   matlab.ui.control.RadioButton
        RemovalelevationsButton       matlab.ui.control.Button
        MaxelevationTextArea          matlab.ui.control.TextArea
        Label_5                       matlab.ui.control.Label
        MinelevationTextArea          matlab.ui.control.TextArea
        Label_4                       matlab.ui.control.Label
        RemovalButton                 matlab.ui.control.Button
        TextArea_6                    matlab.ui.control.TextArea
        TextArea_5                    matlab.ui.control.TextArea
        RemovalchannelButtonGroup     matlab.ui.container.ButtonGroup
        Button_5                      matlab.ui.control.RadioButton
        EnterthresholdButton          matlab.ui.control.RadioButton
        newprofilelocationButton      matlab.ui.control.Button
        TextArea_4                    matlab.ui.control.TextArea
        resamplingintervalTextArea    matlab.ui.control.TextArea
        Label_3                       matlab.ui.control.Label
        DrawingprofilesButton         matlab.ui.control.Button
        SmoothdistanceTextArea        matlab.ui.control.TextArea
        Label_2                       matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        filelocationButton            matlab.ui.control.Button
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        numberMenu                    matlab.ui.container.Menu
        NumberoftherivertobeanalyzedMenu  matlab.ui.container.Menu
        filelocationMenu              matlab.ui.container.Menu
        LocationofriverprofilesfrombackcountingcalculationsMenu  matlab.ui.container.Menu
        newprofilelocationMenu        matlab.ui.container.Menu
        YX346Chiksnksn10Menu          matlab.ui.container.Menu
        Chiksnksn10Menu               matlab.ui.container.Menu
        SmoothdistanceMenu            matlab.ui.container.Menu
        Menu_7                        matlab.ui.container.Menu
        resamplingintervalMenu_2      matlab.ui.container.Menu
        mSAMenu                       matlab.ui.container.Menu
        DrawingprofilesMenu           matlab.ui.container.Menu
        PlottingprofilesbasedoninformationfromtheBankMenu  matlab.ui.container.Menu
        RemovalchannelMenu            matlab.ui.container.Menu
        Menu_11                       matlab.ui.container.Menu
        SAMenu                        matlab.ui.container.Menu
        Menu_22                       matlab.ui.container.Menu
        RemovalMenu                   matlab.ui.container.Menu
        FinalrejectionofstreamsbasedondefinedthresholdsMenu  matlab.ui.container.Menu
        elevationMenu                 matlab.ui.container.Menu
        Menu_16                       matlab.ui.container.Menu
        RemovalelevationsMenu         matlab.ui.container.Menu
        RejectionofinputelevationvaluesMenu  matlab.ui.container.Menu
        knickpointnumberMenu          matlab.ui.container.Menu
        SpecifythenumberoffissurepointsMenu  matlab.ui.container.Menu
        MethodsforfindingMenu         matlab.ui.container.Menu
        logSAMenu                     matlab.ui.container.Menu
        chizMenu                      matlab.ui.container.Menu
        logSAchizMenu                 matlab.ui.container.Menu
        shpfilelocationMenu           matlab.ui.container.Menu
        SpecifyafoldertostorethegeneratedshpfileMenu  matlab.ui.container.Menu
        TextlocationMenu              matlab.ui.container.Menu
        Menu_27                       matlab.ui.container.Menu
        Pnt_divideXYksnksnmaxminchiMenu  matlab.ui.container.Menu
        maxminchiMenu                 matlab.ui.container.Menu
        Pnt_analysisXY3SAksnMenu      matlab.ui.container.Menu
    end

    
    properties (Access = private)
         riverPro double % riverPro 变量保存河流数据 
         m double %数据属性
         maxEle; minEle;
         Pnt;PntK1;PntK2;
         Area;Ele;Chi;Slope;
         Struct;
    end
    
    methods (Access = private)
        
        
        function [R2,a0,a1,std_a1]=Bi_Regress(app,Y,X)
          % 线性回归 Y=a0+a1*X
          Length_X=length(X);
        if Length_X>=3
            R2=corrcoef(Y,X); if length(R2)>1;R2=R2(1,2); R2=R2^2;else R2=R2^2;end
            mean_X=mean(X); mean_Y=mean(Y);
            Sxx=sum((X-mean_X).^2);Syy=sum((Y-mean_Y).^2);
            Sxy=sum((X-mean_X).*(Y-mean_Y));
            a1=Sxy/Sxx; a0=mean_Y-a1*mean_X;
            sigma=sqrt((Syy-a1*Sxy)/(Length_X-2));
            std_a1=sigma/sqrt(Sxx);
        elseif Length_X==2
            R2=1;
            a1=(Y(1)-Y(2))./(X(1)-X(2)); 
            std_a1=0;
            a0=Y(1)-a1*X(1);
        else
          %回归数据不够，不可模拟
          R2=9999;a0=9999;a1=9999;std_a1=9999;    
        end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: newprofilelocationButton
        function selectFolder(app, event)
             % 弹出选择文件夹对话框
    selectedFolder = uigetdir();
    
    % 如果用户取消了操作，则返回
    if isequal(selectedFolder, 0)
        return;
    end
    
    % 将选定的文件夹路径显示在文本框中
    app.TextArea_4.Value = selectedFolder;
        end

        % Button pushed function: filelocationButton
        function readtxt(app, event)
        % 让用户选择要读取的文件
    [filename, filepath] = uigetfile('*.txt', '选择要读取的文件');
    
    % 如果用户没有选择文件，取消操作
    if isequal(filename, 0)
        return;
    end
    % 构造完整的文件路径
    fullFilePath = fullfile(filepath, filename);
    % 在文本框中显示选取的文件地址
    app.TextArea.Value = fullFilePath;
        end

        % Button pushed function: DrawingprofilesButton
        function calculate(app, event)
    % 读取文件
    filePath = app.TextArea.Value{1};
    riverPro_Old_f=load(filePath);
    [~, fileName, ~] = fileparts(filePath);
    % 读取 app.SmoothdistanceTextArea 和 TextArea_3 的值
    SmoothLen_f = str2double(app.SmoothdistanceTextArea.Value{1});
    ResampleWin_f = str2double(app.resamplingintervalTextArea.Value{1});

    %计算坡度与Localksn
    UpLen=riverPro_Old_f(:,3);app.Ele=riverPro_Old_f(:,4);app.Chi=riverPro_Old_f(:,7); app.m=length(UpLen); 

%计算平滑后的高程
SmoothedEle=app.Ele;Localksn=zeros(app.m,1);app.Slope=zeros(app.m,1);
for i=1:app.m
    if abs(UpLen(i)-UpLen(1))<=SmoothLen_f/2 % 当点i与出水口距离小于SmoothLen_f/2，则一直向后寻找直至达到阈值为止
        j=1; while i+j<=app.m && abs(UpLen(i+j)-UpLen(1))<=SmoothLen_f; j=j+1;end; j=j-1; 
        if (i+j)-1<2; j=3-i; end %当参与插值回归点数＜3，强制多选择点，确保插值、回归中有三个点。
        SmoothedEle(i)=interp1(UpLen(1:i+j),app.Ele(1:i+j),UpLen(i));
        b=regress(app.Ele(1:i+j),[ones(size(app.Chi(1:i+j))) app.Chi(1:i+j)]); %regress函数用法，y=a0+a1*x
        Localksn(i)=abs(b(2));
    elseif abs(UpLen(app.m)-UpLen(i))<=SmoothLen_f/2 % 当点i与水头距离小于SmoothLen_f/2，则一直向前寻找直至达到阈值为止
        j=1; while i-j>=1 && abs(UpLen(app.m)-UpLen(i-j))<=SmoothLen_f; j=j+1;end; j=j-1;
        if (i-j)>(app.m-2); j=i-(app.m-2); end %当参与插值回归点数＜3，强制多选择点，确保插值、回归中有三个点。
        SmoothedEle(i)=interp1(UpLen(i-j:app.m),app.Ele(i-j:app.m),UpLen(i));
        b=regress(app.Ele(i-j:app.m),[ones(size(app.Chi(i-j:app.m))) app.Chi(i-j:app.m)]); 
        Localksn(i)=abs(b(2));
    elseif abs(UpLen(i)-UpLen(1))>SmoothLen_f/2 && abs(UpLen(app.m)-UpLen(i))>SmoothLen_f/2 %点与出水口、水头距离都超过SmoothLen_f/2
        j=1; while i-j>=1 && i+j<=app.m && abs(UpLen(i+j)-UpLen(i-j))<=SmoothLen_f; j=j+1; end; j=j-1;
        if j==0; j=1; end %当搜索点的左右相邻点的距离＜SmoothLen_f，强制选择相邻的点，确保插值、回归中有三个点。
        SmoothedEle(i)=interp1(UpLen(i-j:i+j),app.Ele(i-j:i+j),UpLen(i));
        b=regress(app.Ele(i-j:i+j),[ones(size(app.Chi(i-j:i+j))) app.Chi(i-j:i+j)]);
        Localksn(i)=abs(b(2));
    end
end

%计算重采样后的Localksn和Slope
for i=1:app.m
    if abs(SmoothedEle(i)-SmoothedEle(1))<=ResampleWin_f/2 % 当点i与出水口高程小于ResampleWin_f/2，则一直向后寻找直至达到阈值为止
        j=1; while i+j<=app.m && abs(SmoothedEle(i+j)-SmoothedEle(1))<=ResampleWin_f; j=j+1;end; j=j-1; 
        if (i+j)-1<2; j=3-i; end %当参与插值回归点数＜3，强制多选择点，确保插值、回归中有三个点。
        b=regress(SmoothedEle(1:i+j),[ones(size(UpLen(1:i+j))) UpLen(1:i+j)]); %regress函数用法，y=a0+a1*x
        app.Slope(i)=abs(b(2));
    elseif abs(SmoothedEle(app.m)-SmoothedEle(i))<=ResampleWin_f/2 % 当点i与水头高程小于ResampleWin_f/2，则一直向前寻找直至达到阈值为止
        j=1; while i-j>=1 && abs(SmoothedEle(app.m)-SmoothedEle(i-j))<=ResampleWin_f; j=j+1;end; j=j-1;
        if (i-j)>(app.m-2); j=i-(app.m-2); end %当参与插值回归点数＜3，强制多选择点，确保插值、回归中有三个点。
        b=regress(SmoothedEle(i-j:app.m),[ones(size(UpLen(i-j:app.m))) UpLen(i-j:app.m)]); 
        app.Slope(i)=abs(b(2));
    elseif abs(SmoothedEle(i)-SmoothedEle(1))>ResampleWin_f/2 && abs(SmoothedEle(app.m)-SmoothedEle(i))>ResampleWin_f/2 %点与出水口、水头高程都超过ResampleWin_f
        j=1; while i-j>=1 && i+j<=app.m && abs(SmoothedEle(i+j)-SmoothedEle(i-j))<=ResampleWin_f; j=j+1; end; j=j-1;
        if j==0; j=1; end %当搜索点的左右相邻点的高差＜ResampleWin_f，强制选择相邻的点，确保插值、回归中有三个点。
        b=regress(SmoothedEle(i-j:i+j),[ones(size(UpLen(i-j:i+j))) UpLen(i-j:i+j)]);
        app.Slope(i)=abs(b(2));
       
    end
end

app.riverPro=[riverPro_Old_f SmoothedEle Localksn app.Slope];
%存储文件
filePath = app.TextArea_4.Value{1};
newFileName = strcat(fileName, 'New.txt');
newFilePath = fullfile(filePath, newFileName);
writematrix(app.riverPro, newFilePath, 'Delimiter', ' ');

%UIAxes绘制.x-log(A)
%app.UIAxes.Position = [2 402 450 200];
semilogy(app.UIAxes, app.riverPro(:,3), app.riverPro(:,6));
ylim(app.UIAxes, [min(app.riverPro(:,6)), max(app.riverPro(:,6))]);

%UIAxes_2绘制，x-log(S)
%app.UIAxes_2.Position = [2 199 450 200];
semilogy(app.UIAxes_2,app.riverPro(:,3),app.riverPro(:,10),'+');
ylim(app.UIAxes_2,[10^(-3),10^0]);

%UIAxes_3绘制，A-log(S)
%app.UIAxes_3.Position = [2 1 450 200];
loglog(app.UIAxes_3, app.riverPro(:,6),app.riverPro(:,10),'+');
ylim(app.UIAxes_3,[10^(-3),10^0]);
hold(app.UIAxes_3, 'on');
%xlim(app.UIAxes_3,[min(app.riverPro(:,6)),max(app.riverPro(:,6))]);

 %UIAxes2绘制，L-z
plot(app.UIAxes2,app.riverPro(:,3),app.riverPro(:,4));
app.minEle=min(app.riverPro(:,4))-100;app.maxEle=max(app.riverPro(:,4))+100;
ylim(app.UIAxes2,[app.minEle,app.maxEle]);
hold(app.UIAxes2, 'on');
        end

        % Selection changed function: RemovalchannelButtonGroup
        function radioRemovalchannelButtonGroupCallback(app, event)

             % 获取当前选中的单选按钮
          selectedButton = app.RemovalchannelButtonGroup.SelectedObject;

    % 根据选中的按钮执行不同的操作
    if selectedButton == app.EnterthresholdButton
        % 执行第一个按钮被选中时的操作，例如允许手动输入文本
        app.TextArea_5.Visible = 'on';
        app.TextArea_6.Visible = 'off';
    elseif selectedButton == app.Button_5
        app.TextArea_5.Visible = 'off';
        app.TextArea_6.Visible = 'on';
        % 执行第二个按钮被选中时的操作，例如弹出图形窗口选择点
     % 创建一个新的图形窗口，并设置位置和大小
      h1 = figure('Position', [657, 339, 773, 300]);
      ax = axes(h1);
     % 使用 loglog 函数在坐标轴上绘制图形
     loglog(ax, app.riverPro(:,6), app.riverPro(:,10), '+');
     ylim(ax, [10^(-3), 10^0]);
     [x, ~] = ginput(1);
     app.TextArea_6.Value = num2str(x);
     close(h1); % 关闭图形窗口
    end
     
        end

        % Button pushed function: RemovalButton
        function removing_channels(app, event)
        if isempty(app.TextArea_6.Value)%读取文本框中的值
              value = str2double(app.TextArea_5.Value{1});
        else
              value = str2double(app.TextArea_6.Value{1});
        end
        i=app.m;    
         while app.riverPro(i,6)<value; i=i-1; end
        %UIAxes2绘制，L-z
        plot(app.UIAxes2,app.riverPro(i,3),app.riverPro(i,4),'*'); %在UpLen-z上标记河道顶点 
        hold(app.UIAxes2, 'on');
         app.riverPro=app.riverPro(1:i,:); % 对于崩积河道 剔除
        % Y、X坐标，溯源距离、高程、流向、汇水面积、Chi距离
        app.Chi=app.riverPro(:,7);app.Ele=app.riverPro(:,4);
        plot(app.UIAxes2_2,app.Chi,app.Ele,'g','linewidth',3);
        hold(app.UIAxes2_2, 'on');
        end

        % Button pushed function: RemovalelevationsButton
        function removing_Elve(app, event)
           Hmin = str2double(app.MinelevationTextArea.Value{1});
           Hmax = str2double(app.MaxelevationTextArea.Value{1});

 %剔除小的高程
while Hmin>0
    for iErase=1:app.m
        if app.Ele(iErase)>minorEle; app.riverPro=app.riverPro(iErase:app.m,:); break; end % 找到大于设置的小值的全部留下；结束循环
    end
end
% 剔除小的高程后新的河流纵剖面
app.Chi=app.riverPro(:,7);app.Ele=app.riverPro(:,4); app.m=length(app.Chi);
%plot(app.UIAxes2_2,Chi,Ele,'m','linewidth',3);
%剔除大的高程
while Hmax>0
    for jErase=1:app.m
        if app.Ele(jErase)>largeEle; app.riverPro=app.riverPro(1:(jErase-1),:); break; end 
    end 
end
% 剔除设置的高程后新的河流纵剖面 
app.Chi=app.riverPro(:,7);app.Ele=app.riverPro(:,4);app.Area = app.riverPro(:,6);app.Slope=app.riverPro(:,10);
plot(app.UIAxes2_2,app.Chi,app.Ele,'r','linewidth',3);
ylim(app.UIAxes2_2,[app.minEle,app.maxEle]);
hold(app.UIAxes2_2, 'on');
        end

        % Selection changed function: MethodsforfindingButtonGroup
        function point_selection(app, event)

            % 获取当前选中的单选按钮
          selectedButton = app.MethodsforfindingButtonGroup.SelectedObject;
         Num= str2double(app.knickpointTextArea.Value{1});
    % 根据选中的按钮执行不同的操作
    if Num == 0
        % 若不存在列点
        app.logSAButton.Text = '无需选择';app.Button_9.Text= '无需选择';
        str = "";
        str = str + "1" + "——" + num2str(app.m) ;

    else
        if selectedButton == app.logSAButton %
        app.logSAButton.Text = 'logS-A图';app.Button_9.Text= 'Chi-z图';
         h2 = figure('Position', [657, 339, 773, 300]);
         ax = axes(h2);

     % 使用 loglog 函数在坐标轴上绘制图形
        loglog(ax, app.riverPro(:,6), app.riverPro(:,10), '+');
        ylim(ax, [10^(-3), 10^0]);
        [PntArea,~]=ginput(Num);
        PntArea=sort(PntArea,'descend');
        disp(PntArea);disp(app.m);
        PntNum=[]; PntNum=[PntNum;1]; % 分界点在高程剖面对应的序号，包含第一个点、最后一个起始点
         for di=1:Num %第di个分界点
            for k=1:app.m-1
                if (PntArea(di)<=app.Area(k) && PntArea(di)>=app.Area(k+1))
                    PntNum=[PntNum;k]; 
                    break;
                end
            end
            loglog(app.UIAxes_3,[app.Area(k),app.Area(k)],[10^(-3),10^0],'r--'); %在S-A图上绘制分段线
            hold(app.UIAxes_3, 'on');
         end
        PntNum=[PntNum;app.m]; % 高程间隔的编号,PntNum至少包含首尾2个
        %Num=length(PntNum)-2; % 实际的分割点数目，PntNum是包含首尾的，因此，自然比分割点多2个
   close(h2); % 关闭图形窗口
     str = "";
       for i = 1:length(PntNum)-1
       str = str  + num2str(PntNum(i)) + "————" + num2str(PntNum(i+1)) ;
       end
     app.Pnt=PntNum;
  
     elseif selectedButton == app.Button_9
         app.logSAButton.Text = 'logS-A图';app.Button_9.Text= 'Chi-z图';
         h3 = figure('Position', [593,346,773,410]);
         ax = axes(h3);
      
         plot(ax,app.Chi,app.Ele,'r','linewidth',3);
         [PntChi,~]=ginput(Num);
         PntChi=sort(PntChi);
            % 寻找确切的分界点编号
        PntNum=[]; PntNum=[PntNum;1]; % 分界点在高程剖面对应的序号，包含第一个点、最后一个起始点
        for j=1:Num %第j个分界点的高程
            for k=1:app.m-1 
                if (PntChi(j)>=app.Chi(k) && PntChi(j)<=app.Chi(k+1)) 
                    PntNum=[PntNum;k];
                    break;
                end
            end
             plot(app.UIAxes2_2,[app.Chi(k), app.Chi(k)],[app.Ele(k)-200,app.Ele(k)+200],'k--','linewidth',2); %在Chi-z上标记分段线 
             hold(app.UIAxes2_2, 'on');
        end
        PntNum=[PntNum;app.m]; % 高程间隔的编号
        %Num=length(PntNum)-2; % PntNum是包含首尾的，因此，自然比分割点多2个
         close(h3); % 关闭图形窗口
           str = "";
       for i = 1:length(PntNum)-1
       str = str +  num2str(PntNum(i)) + "——" + num2str(PntNum(i+1)) + ",";
       end
   app.Pnt=PntNum;
        end
    end


        end

        % Button pushed function: CalculateButton
        function calculate_2(app, event)
            Num= str2double(app.knickpointTextArea.Value{1});
            river_i= str2double(app.numberTextArea.Value{1});
           PntKs=[];Pnt_analysis=[];
           if  Num==0
    [~,z0,ksn,std_ksn]=Bi_Regress(app,app.Ele,app.Chi); % 线性回归 Y=a0+a1*X
    plot(app.UIAxes2_2,app.Chi,app.Chi.*ksn+z0,'k'); %在Chi-z图画拟合线 
    text(app.UIAxes2_2,max(app.Chi)/2,max(app.Ele),['z=Chi.*',num2str(ksn),'±',num2str(std_ksn),'+',num2str(z0)]); % 这个是在Fig2上面画的
    hold(app.UIAxes2_2, 'on');

    [~,log_ks,theta,std_theta]=Bi_Regress(app,log(app.Slope),log(app.Area));
    loglog(app.UIAxes_3,app.Area,(app.Area.^(theta)).*(exp(log_ks)),'k'); %在S-A图上绘制分段线
    text(app.UIAxes_3, max(app.Area)/2, max(app.Slope)/2, ['θ=',num2str(theta),'±',num2str(std_theta)]);
    hold(app.UIAxes_3, 'on');
    %% 输出分界点信息：X、Y坐标，河段凹度、误差，ksn、误差，校正后ksn、误差，折点汇水面积（Km2，min,max），折点高程(max,min)、最高折点的溯源距离、chi值
    PntKs=[PntKs;[river_i,app.riverPro(app.m,2),app.riverPro(app.m,1),-theta,std_theta,ksn,std_ksn,(app.riverPro(app.m,6))./(1e6),(app.riverPro(1,6))./(1e6),app.riverPro(app.m,4),app.riverPro(1,4),app.riverPro(app.m,3),app.riverPro(app.m,7)]];
    Pnt_analysis=[Pnt_analysis;[river_i,app.riverPro(app.m,1),app.riverPro(app.m,2),app.riverPro(app.m,3),-theta,log_ks]];  
    app.PntK1=PntKs; app.PntK2=Pnt_analysis;
    %% 生成矢量线,新的riverPro: 纵剖面点的平面坐标Y、X、溯源距离、高程、流向、汇水面积、Chi距离、平滑高程、局部ksn、坡度
    Sfea=struct();
    
    Sfea.Geometry='Line';
    Xcor=app.riverPro(:,2); Xcor=Xcor';Sfea.X=[Xcor,NaN];
    Ycor=app.riverPro(:,1); Ycor=Ycor';Sfea.Y=[Ycor,NaN];
    Sfea.ID=0; Sfea.RiverNo=river_i; 
    Sfea.theta=-theta; Sfea.std_theta=std_theta;
    Sfea.ksn=ksn; Sfea.std_ksn=std_ksn;
    Sfea.Amin_km=(app.riverPro(app.m,6))./(1e6); Sfea.Amax_km=(app.riverPro(1,6))./(1e6);
    Sfea.Emax_m=app.riverPro(app.m,4); Sfea.Emin_m=app.riverPro(1,4);
    Sfea.Lenmax_m=app.riverPro(app.m,3); Sfea.Chimax=app.riverPro(app.m,7); 
    app.Struct=Sfea;
        
else % >=1个分界点 
    %% 填充 PntKs    % 第一段

    Ele_1=app.Ele(app.Pnt(1):app.Pnt(2));   Chi_1=app.Chi(app.Pnt(1):app.Pnt(2)); Area_1=app.Area(app.Pnt(1):app.Pnt(2));  Slope_1=app.Slope(app.Pnt(1):app.Pnt(2));
    plot(app.UIAxes2,app.riverPro(app.Pnt(2),3),app.riverPro(app.Pnt(2),4),'kx');%在UpLen-z上标记河道裂点 
    hold(app.UIAxes2, 'on');
   
    [~,z0_1,ksn_1,std_ksn_1]=Bi_Regress(app,Ele_1,Chi_1); % 线性回归 Y=a0+a1*X
    plot(app.UIAxes2_2,Chi_1,Chi_1.*ksn_1+z0_1,'k'); %在Chi-z图画拟合线
    text(app.UIAxes2_2,max(Chi_1)/2,max(Ele_1),['z1=Chi.*',num2str(ksn_1),'±',num2str(std_ksn_1),'+',num2str(z0_1)]); %在Chi-z图画拟合线
    hold(app.UIAxes2_2, 'on');
    
    [~,log_ks_1,theta_1,std_theta_1]=Bi_Regress(app,log(Slope_1),log(Area_1));
    loglog(app.UIAxes_3,Area_1,(Area_1.^(theta_1)).*(exp(log_ks_1)),'k'); %在S-A图上绘制分段线
    text(app.UIAxes_3,max(Area_1)/2,max(Slope_1)/2,['θ=',num2str(theta_1),'±',num2str(std_theta_1)]);
    hold(app.UIAxes_3, 'on');
  
    %% 输出分界点信息：河流编号，X、Y坐标，折点以下河段凹度、误差，ksn、误差，校正后ksn、误差，折点汇水面积（Km2，min,max），折点高程(max,min)、最高折点的溯源距离、chi值
    PntKs=[PntKs;[river_i,app.riverPro(app.Pnt(2),2),app.riverPro(app.Pnt(2),1),-theta_1,std_theta_1,ksn_1,std_ksn_1,(app.riverPro(app.Pnt(2),6))./(1e6),(app.riverPro(app.Pnt(1),6))./(1e6),...
        app.riverPro(app.Pnt(2),4),app.riverPro(app.Pnt(1),4),app.riverPro(app.Pnt(2),3),app.riverPro(app.Pnt(2),7)]];
    Pnt_analysis=[Pnt_analysis;[river_i,app.riverPro(app.Pnt(2),1),app.riverPro(app.Pnt(2),2),app.riverPro(app.Pnt(2),3),-theta_1,log_ks_1]];  
    %% 生成矢量线,新的riverPro: 纵剖面点的平面坐标Y、X、溯源距离、高程、流向、汇水面积、Chi距离、平滑高程、局部ksn、坡度
    Sfea=struct();
    
    Sfea(1).Geometry='Line';
    Xcor=app.riverPro(app.Pnt(1):app.Pnt(2),2); Xcor=Xcor'; Sfea(1).X=[Xcor,NaN];
    Ycor=app.riverPro(app.Pnt(1):app.Pnt(2),1); Ycor=Ycor'; Sfea(1).Y=[Ycor,NaN];
    Sfea(1).ID=0; Sfea.RiverNo=river_i; 
    Sfea(1).theta=-theta_1; Sfea(1).std_theta=std_theta_1;  
    Sfea(1).ksn=ksn_1;     Sfea(1).std_ksn=std_ksn_1; 
    Sfea(1).Amin_km=(app.riverPro(app.Pnt(2),6))./(1e6); Sfea(1).Amax_km=(app.riverPro(app.Pnt(1),6))./(1e6);
    Sfea(1).Emax_m=app.riverPro(app.Pnt(2),4); Sfea(1).Emin_m=app.riverPro(app.Pnt(1),4);
    Sfea(1).Lenmax_m=app.riverPro(app.Pnt(2),3); Sfea(1).Chimax=app.riverPro(app.Pnt(2),7);
        
    %% ************剩余的河段*************************************************
    for j=2:(Num+1)
        Ele_j=app.Ele((app.Pnt(j)+1):app.Pnt(j+1)); Chi_j=app.Chi((app.Pnt(j)+1):app.Pnt(j+1)); Area_j=app.Area((app.Pnt(j)+1):app.Pnt(j+1)); Slope_j=app.Slope((app.Pnt(j)+1):app.Pnt(j+1));
         plot(app.UIAxes2,app.riverPro(app.Pnt(j+1),3),app.riverPro(app.Pnt(j+1),4),'kx');%在UpLen-z上标记河道裂点 
         hold(app.UIAxes2, 'on');

        [~,z0_j,ksn_j,std_ksn_j]=Bi_Regress(app,Ele_j,Chi_j); % 线性回归 Y=a0+a1*X
         plot(app.UIAxes2_2,Chi_j,Chi_j.*ksn_j+z0_j,'k'); %在Chi-z图画拟合线
         text(app.UIAxes2_2,max(Chi_j)/2,max(Ele_j),['R',num2str(j),'z=Chi.*',num2str(ksn_j),'±',num2str(std_ksn_j),'+',num2str(z0_j)]); %在Chi-z图画拟合线
         hold(app.UIAxes2_2, 'on');
        
        [~,log_ks_j,theta_j,std_theta_j]=Bi_Regress(app,log(Slope_j),log(Area_j));
        loglog(app.UIAxes_3,Area_j,(Area_j.^(theta_j)).*(exp(log_ks_j)),'k'); %在S-A图上绘制分段线
        text(app.UIAxes_3,max(Area_j)/2,max(Slope_j)/2,['R',num2str(j),'=','θ=',num2str(theta_j),'±',num2str(std_theta_j)]);
        hold(app.UIAxes_3, 'on');
        
        %% 输出分界点信息：河流编号，X、Y坐标，折点以下河段凹度、误差，ksn、误差，校正后ksn、误差，折点汇水面积（Km2，min,max），折点高程(max,min)、最高折点的溯源距离、chi值
        PntKs=[PntKs;[river_i,app.riverPro(app.Pnt(j+1),2),app.riverPro(app.Pnt(j+1),1),-theta_j,std_theta_j,ksn_j,std_ksn_j,(app.riverPro(app.Pnt(j+1),6))./(1e6),(app.riverPro(app.Pnt(j),6))./(1e6),...
            app.riverPro(app.Pnt(j+1),4),app.riverPro(app.Pnt(j),4),app.riverPro(app.Pnt(j+1),3),app.riverPro(app.Pnt(j+1),7)]];
        Pnt_analysis=[Pnt_analysis;[river_i,app.riverPro(app.Pnt(j+1),1),app.riverPro(app.Pnt(j+1),2),app.riverPro(app.Pnt(j+1),3),-theta_j,log_ks_j]];  
        app.PntK1=PntKs; app.PntK2=Pnt_analysis;
       %% 生成矢量线,新的riverPro: 纵剖面点的平面坐标Y、X、溯源距离、高程、流向、汇水面积、Chi距离、平滑高程、局部ksn、坡度
         Sfea(j).Geometry='Line';
        Xcor=app.riverPro((app.Pnt(j)+1):app.Pnt(j+1),2); Xcor=Xcor'; Sfea(j).X=[Xcor,NaN];
        Ycor=app.riverPro((app.Pnt(j)+1):app.Pnt(j+1),1); Ycor=Ycor'; Sfea(j).Y=[Ycor,NaN];
        Sfea(j).ID=0; Sfea(j).RiverNo=river_i; 
        Sfea(j).theta=-theta_j; Sfea(j).std_theta=std_theta_j; 
        Sfea(j).ksn=ksn_j; Sfea(j).std_ksn=std_ksn_j; 
        Sfea(j).Amin_km=(app.riverPro(app.Pnt(j+1),6))./(1e6); Sfea(j).Amax_km=(app.riverPro(app.Pnt(j),6))./(1e6);
        Sfea(j).Emax_m=app.riverPro(app.Pnt(j+1),4); Sfea(j).Emin_m=app.riverPro(app.Pnt(j),4);
        Sfea(j).Lenmax_m=app.riverPro(app.Pnt(j+1),3); Sfea(j).Chimax=app.riverPro(app.Pnt(j+1),7);
    end
    app.Struct=Sfea;
end 
        end

        % Button pushed function: shpfilelocationButton
        function selectFolder2(app, event)
               % 弹出选择文件夹对话框
    selectedFolder = uigetdir();
    
    % 如果用户取消了操作，则返回
    if isequal(selectedFolder, 0)
        return;
    end
    
    % 将选定的文件夹路径显示在文本框中
    app.TextArea_10.Value = selectedFolder;
        end

        % Button pushed function: TextlocationButton
        function selectFolder3(app, event)
     % 弹出选择文件夹对话框
    selectedFolder = uigetdir();
    
    % 如果用户取消了操作，则返回
    if isequal(selectedFolder, 0)
        return;
    end
    
    % 将选定的文件夹路径显示在文本框中
    app.TextArea_11.Value = selectedFolder;
        end

        % Button pushed function: StoringinformationButton
        function store_information(app, event)
            
          folderPath = app.TextArea_10.Value;
          folderName = 'Sfeature';
          fileName = strcat(folderName, num2str(str2double(app.numberTextArea.Value{1})), '.shp');
          fullPath = fullfile(folderPath, fileName);
          shapewrite(app.Struct, char(fullPath));
         
           folderPath1 = app.TextArea_10.Value; folderPath2 = app.TextArea_11.Value;
           fileName1 = 'Pnt_divide.txt'; fileName2 = 'Pnt_analysis.txt';
           fullPath1 = fullfile(folderPath1, fileName1);fullPath2 = fullfile(folderPath2, fileName2);
           fullPath1 = fullPath1{1}; fullPath2 = fullPath2{1}; % 去除大括号

           disp(fullPath1);disp(fullPath2);
           if exist(fullPath1, 'file') ~= 2
           % 如果文件不存在，则创建文件并写入数据
           fid = fopen(fullPath1, 'w');
           else
            % 如果文件存在，则打开文件
          fid = fopen(fullPath1, 'a+'); 
           end
           [Pnt_m,Pnt_n]=size(app.PntK1); %fid=fopen(fullPath1,'a+');
          for i=1:Pnt_m
            for j=1:Pnt_n-1
                 fprintf(fid,'%f\t',app.PntK1(i,j));
            end
                 fprintf(fid,'%f',app.PntK1(i,Pnt_n));fprintf(fid,'\n');
          end
        fclose(fid);
          if exist(fullPath2, 'file') ~= 2
           fid = fopen(fullPath2, 'w');
           else
            % 如果文件存在，则打开文件
           fid = fopen(fullPath1, 'a+'); 
          end
          [analysis_m,analysis_n]=size(app.PntK2);%fid=fopen(fullPath2,'a+');
         for i=1:analysis_m
           for j=1:analysis_n-1
                fprintf(fid,'%f\t',app.PntK2(i,j));
           end
                fprintf(fid,'%f',app.PntK2(i,analysis_n));fprintf(fid,'\n');
          end
       fclose(fid);
       msg = '运算已结束';title = '提示';
       msgbox(msg, title);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RiverelevationprofileanalysisPanel = uipanel(app.UIFigure);
            app.RiverelevationprofileanalysisPanel.Title = 'River elevation profile analysis';
            app.UIAxes = uiaxes(app.RiverelevationprofileanalysisPanel);
            xlabel(app.UIAxes, 'Length (m)')
            ylabel(app.UIAxes, 'Area (m^2)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_3 = uiaxes(app.RiverelevationprofileanalysisPanel);
            xlabel(app.UIAxes_3, 'Area (m^2)')
            ylabel(app.UIAxes_3, 'Slope')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_2 = uiaxes(app.RiverelevationprofileanalysisPanel);
            xlabel(app.UIAxes_2, 'Length (m)')
            ylabel(app.UIAxes_2, 'Slope')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2 = uiaxes(app.RiverelevationprofileanalysisPanel);
            xlabel(app.UIAxes2, 'Length (m)')
            ylabel(app.UIAxes2, 'Elevation (m)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.RiverelevationprofileanalysisPanel);
            xlabel(app.UIAxes2_2, 'Chi ')
            ylabel(app.UIAxes2_2, 'Elevation (m)')
            zlabel(app.UIAxes2_2, 'Z')
            app.filelocationButton = uibutton(app.RiverelevationprofileanalysisPanel, 'push');
            app.filelocationButton.ButtonPushedFcn = createCallbackFcn(app, @readtxt, true);
            app.filelocationButton.Text = 'file location';
            app.TextArea = uitextarea(app.RiverelevationprofileanalysisPanel);
            app.Label_2 = uilabel(app.RiverelevationprofileanalysisPanel);
            app.Label_2.Text = 'Smooth distance';
            app.SmoothdistanceTextArea = uitextarea(app.RiverelevationprofileanalysisPanel);
            app.SmoothdistanceTextArea.Value = {'50'};
            app.DrawingprofilesButton = uibutton(app.RiverelevationprofileanalysisPanel, 'push');
            app.DrawingprofilesButton.ButtonPushedFcn = createCallbackFcn(app, @calculate, true);
            app.DrawingprofilesButton.Text = 'Drawing profiles';
            app.Label_3 = uilabel(app.RiverelevationprofileanalysisPanel);
            app.Label_3.Text = 'resampling interval';
            app.resamplingintervalTextArea = uitextarea(app.RiverelevationprofileanalysisPanel);
            app.resamplingintervalTextArea.Value = {'20'};
            app.TextArea_4 = uitextarea(app.RiverelevationprofileanalysisPanel);
            app.newprofilelocationButton = uibutton(app.RiverelevationprofileanalysisPanel, 'push');
            app.newprofilelocationButton.ButtonPushedFcn = createCallbackFcn(app, @selectFolder, true);
            app.newprofilelocationButton.Text = 'new profile location';
            app.RemovalchannelButtonGroup = uibuttongroup(app.RiverelevationprofileanalysisPanel);
            app.RemovalchannelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @radioRemovalchannelButtonGroupCallback, true);
            app.RemovalchannelButtonGroup.Title = 'Removal channel';
            app.EnterthresholdButton = uiradiobutton(app.RemovalchannelButtonGroup);
            app.EnterthresholdButton.Text = 'Enter  threshold:';
            app.EnterthresholdButton.Value = true;
            app.Button_5 = uiradiobutton(app.RemovalchannelButtonGroup);
            app.Button_5.Text = 'Select  threshold:';
            app.TextArea_5 = uitextarea(app.RiverelevationprofileanalysisPanel);
            app.TextArea_6 = uitextarea(app.RiverelevationprofileanalysisPanel);
            app.TextArea_6.Editable = 'off';
            app.RemovalButton = uibutton(app.RiverelevationprofileanalysisPanel, 'push');
            app.RemovalButton.ButtonPushedFcn = createCallbackFcn(app, @removing_channels, true);
            app.RemovalButton.Text = 'Removal';
            app.Label_4 = uilabel(app.RiverelevationprofileanalysisPanel);
            app.Label_4.Text = 'Min elevation';
            app.MinelevationTextArea = uitextarea(app.RiverelevationprofileanalysisPanel);
            app.MinelevationTextArea.Value = {'-1'};
            app.Label_5 = uilabel(app.RiverelevationprofileanalysisPanel);
            app.Label_5.Text = 'Maxelevation';
            app.MaxelevationTextArea = uitextarea(app.RiverelevationprofileanalysisPanel);
            app.MaxelevationTextArea.Value = {'-1'};
            app.RemovalelevationsButton = uibutton(app.RiverelevationprofileanalysisPanel, 'push');
            app.RemovalelevationsButton.ButtonPushedFcn = createCallbackFcn(app, @removing_Elve, true);
            app.RemovalelevationsButton.Text = 'Removal elevations';
            app.MethodsforfindingButtonGroup = uibuttongroup(app.RiverelevationprofileanalysisPanel);
            app.MethodsforfindingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @point_selection, true);
            app.MethodsforfindingButtonGroup.Title = 'Methods for finding';
            app.logSAButton = uiradiobutton(app.MethodsforfindingButtonGroup);
            app.logSAButton.Text = 'logS-A';
            app.logSAButton.Value = true;
            app.Button_9 = uiradiobutton(app.MethodsforfindingButtonGroup);
            app.Button_9.Text = 'Chi-z  ';
            app.Label_6 = uilabel(app.RiverelevationprofileanalysisPanel);
            app.Label_6.Text = 'knickpoint';
            app.knickpointTextArea = uitextarea(app.RiverelevationprofileanalysisPanel);
            app.knickpointTextArea.Value = {'0'};
            app.TextArea_10 = uitextarea(app.RiverelevationprofileanalysisPanel);
            app.shpfilelocationButton = uibutton(app.RiverelevationprofileanalysisPanel, 'push');
            app.shpfilelocationButton.ButtonPushedFcn = createCallbackFcn(app, @selectFolder2, true);
            app.shpfilelocationButton.Text = 'shp file location';
            app.TextlocationButton = uibutton(app.RiverelevationprofileanalysisPanel, 'push');
            app.TextlocationButton.ButtonPushedFcn = createCallbackFcn(app, @selectFolder3, true);
            app.TextlocationButton.Text = 'Text  location';
            app.TextArea_11 = uitextarea(app.RiverelevationprofileanalysisPanel);
            app.CalculateButton = uibutton(app.RiverelevationprofileanalysisPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculate_2, true);
            app.CalculateButton.Text = 'Calculate';
            app.StoringinformationButton = uibutton(app.RiverelevationprofileanalysisPanel, 'push');
            app.StoringinformationButton.ButtonPushedFcn = createCallbackFcn(app, @store_information, true);
            app.StoringinformationButton.Text = 'Storing  information';
            app.Label_9 = uilabel(app.RiverelevationprofileanalysisPanel);
            app.Label_9.Text = 'number';
            app.numberTextArea = uitextarea(app.RiverelevationprofileanalysisPanel);
            app.numberTextArea.Value = {'1'};
            app.Hyperlink = uihyperlink(app.RiverelevationprofileanalysisPanel);
            app.Hyperlink.Text = 'Help';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.numberMenu = uimenu(app.ContextMenu);
            app.numberMenu.Text = 'number';
            app.NumberoftherivertobeanalyzedMenu = uimenu(app.numberMenu);
            app.NumberoftherivertobeanalyzedMenu.Text = 'Number of the river to be analyzed';
            app.filelocationMenu = uimenu(app.ContextMenu);
            app.filelocationMenu.Text = 'file location';
            app.LocationofriverprofilesfrombackcountingcalculationsMenu = uimenu(app.filelocationMenu);
            app.LocationofriverprofilesfrombackcountingcalculationsMenu.Text = 'Location of river profiles from backcounting calculations';
            app.newprofilelocationMenu = uimenu(app.ContextMenu);
            app.newprofilelocationMenu.Text = 'new profile location';
            app.YX346Chiksnksn10Menu = uimenu(app.newprofilelocationMenu);
            app.YX346Chiksnksn10Menu.Text = '   A new profile is formed after the calculation, including the plane coordinates of the longitudinal profile points Y, X, trace distance (3), elevation (4), flow direction, catchment area (6), Chi distance, segmented ksn, smoothed elevation, localized ksn, slope (10)';
            app.Chiksnksn10Menu = uimenu(app.newprofilelocationMenu);
            app.Chiksnksn10Menu.Text = ', flow direction, catchment area (6), Chi distance, segmented ksn, smoothed elevation, localized ksn, slope (10)';
            app.SmoothdistanceMenu = uimenu(app.ContextMenu);
            app.SmoothdistanceMenu.Text = 'Smooth distance';
            app.Menu_7 = uimenu(app.SmoothdistanceMenu);
            app.Menu_7.Text = 'How many meter intervals are smoothed at the trace distance location to make the profile smooth';
            app.resamplingintervalMenu_2 = uimenu(app.ContextMenu);
            app.resamplingintervalMenu_2.Text = 'resampling interval';
            app.mSAMenu = uimenu(app.resamplingintervalMenu_2);
            app.mSAMenu.Text = 'How many m of elevation are sampled to determine the amount of S-A plot data';
            app.DrawingprofilesMenu = uimenu(app.ContextMenu);
            app.DrawingprofilesMenu.Text = 'Drawing profiles';
            app.PlottingprofilesbasedoninformationfromtheBankMenu = uimenu(app.DrawingprofilesMenu);
            app.PlottingprofilesbasedoninformationfromtheBankMenu.Text = 'Plotting profiles based on information from the Bank';
            app.RemovalchannelMenu = uimenu(app.ContextMenu);
            app.RemovalchannelMenu.Text = 'Removal channel';
            app.Menu_11 = uimenu(app.RemovalchannelMenu);
            app.Menu_11.Text = 'Choose to enter the catchment area and streams below the entered value will be excluded';
            app.SAMenu = uimenu(app.RemovalchannelMenu);
            app.SAMenu.Text = 'Select the S-A plot, then select the threshold at the new window that pops up to cull the sub-threshold streams';
            app.Menu_22 = uimenu(app.RemovalchannelMenu);
            app.Menu_22.Text = 'The value can be selected multiple times here and is displayed in the text box afterwards';
            app.RemovalMenu = uimenu(app.ContextMenu);
            app.RemovalMenu.Text = 'Removal';
            app.FinalrejectionofstreamsbasedondefinedthresholdsMenu = uimenu(app.RemovalMenu);
            app.FinalrejectionofstreamsbasedondefinedthresholdsMenu.Text = 'Final rejection of streams based on defined thresholds';
            app.elevationMenu = uimenu(app.ContextMenu);
            app.elevationMenu.Text = 'elevation';
            app.Menu_16 = uimenu(app.elevationMenu);
            app.Menu_16.Text = 'Eliminate useless elevation values in the profile, if no special requirements, are -1 can be';
            app.RemovalelevationsMenu = uimenu(app.ContextMenu);
            app.RemovalelevationsMenu.Text = 'Removal elevations';
            app.RejectionofinputelevationvaluesMenu = uimenu(app.RemovalelevationsMenu);
            app.RejectionofinputelevationvaluesMenu.Text = 'Rejection of input elevation values';
            app.knickpointnumberMenu = uimenu(app.ContextMenu);
            app.knickpointnumberMenu.Text = 'knickpoint number';
            app.SpecifythenumberoffissurepointsMenu = uimenu(app.knickpointnumberMenu);
            app.SpecifythenumberoffissurepointsMenu.Text = 'Specify the number of fissure points';
            app.MethodsforfindingMenu = uimenu(app.ContextMenu);
            app.MethodsforfindingMenu.Text = 'Methods for finding';
            app.logSAMenu = uimenu(app.MethodsforfindingMenu);
            app.logSAMenu.Text = 'After selecting the logS-A plot, the corresponding number of cleavage points is selected in a new pop-up window.';
            app.chizMenu = uimenu(app.MethodsforfindingMenu);
            app.chizMenu.Text = 'After selecting the chi-z diagram, the corresponding number of cleavage points is selected in a new pop-up window.';
            app.logSAchizMenu = uimenu(app.MethodsforfindingMenu);
            app.logSAchizMenu.Text = 'Since the default option has no effect, if you choose logS-A, you can switch to chi-z, then switch back.';
            app.shpfilelocationMenu = uimenu(app.ContextMenu);
            app.shpfilelocationMenu.Text = 'shp file location';
            app.SpecifyafoldertostorethegeneratedshpfileMenu = uimenu(app.shpfilelocationMenu);
            app.SpecifyafoldertostorethegeneratedshpfileMenu.Text = 'Specify a folder to store the generated shp file';
            app.TextlocationMenu = uimenu(app.ContextMenu);
            app.TextlocationMenu.Text = 'Text  location';
            app.Menu_27 = uimenu(app.TextlocationMenu);
            app.Menu_27.Text = 'Store two files, one for segmentation point information and one for paleo-elevation analysis information';
            app.Pnt_divideXYksnksnmaxminchiMenu = uimenu(app.TextlocationMenu);
            app.Pnt_divideXYksnksnmaxminchiMenu.Text = 'Divide information Pnt_divide: river number, X and Y coordinates, concavity and error of the section below the fold, ksn and error, corrected ksn and error, catchment area of the fold, elevation of the fold (max,min), traceability distance of the highest fold, chi value';
            app.maxminchiMenu = uimenu(app.TextlocationMenu);
            app.maxminchiMenu.Text = '  Folding point elevation (max,min), traceability distance of the highest folding point, chi value';
            app.Pnt_analysisXY3SAksnMenu = uimenu(app.TextlocationMenu);
            app.Pnt_analysisXY3SAksnMenu.Text = 'Paleo-elevation analysis information Pnt_analysis: river number, X, Y coordinates Retracement distance (3) S-A concavity,ksn';
            % Assign app.ContextMenu
            app.Hyperlink.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RiverProAnalysis_en

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end