classdef RiverProInfo_en < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BackcountrycalculationofriverelevationprofilesPanel  matlab.ui.container.Panel
        DefineButton                 matlab.ui.control.Button
        StoragelocationLabel         matlab.ui.control.Label
        SelectfilelocationButton_5   matlab.ui.control.Button
        TextArea_7                   matlab.ui.control.TextArea
        SelectfilelocationButton_4   matlab.ui.control.Button
        SelectfilelocationButton_3   matlab.ui.control.Button
        SelectfilelocationButton_2   matlab.ui.control.Button
        TextArea_2                   matlab.ui.control.TextArea
        FlowLabel                    matlab.ui.control.Label
        SelectfilelocationButton     matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        ElevationLabel               matlab.ui.control.Label
        HideHelpButton               matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        referenceconcavityTextArea   matlab.ui.control.TextArea
        referenceconcavityTextAreaLabel  matlab.ui.control.Label
        TextArea_4                   matlab.ui.control.TextArea
        TextArea_3                   matlab.ui.control.TextArea
        ElevationMinTextArea         matlab.ui.control.TextArea
        ElevationMinTextAreaLabel    matlab.ui.control.Label
        RiversourcecoordinatesLabel  matlab.ui.control.Label
        FlowrateLabel                matlab.ui.control.Label
        instructionPanel             matlab.ui.container.Panel
        TextArea_8                   matlab.ui.control.TextArea
        ContextMenu                  matlab.ui.container.ContextMenu
        elevationMenu                matlab.ui.container.Menu
        ASCIIArcGISASCIIMenu         matlab.ui.container.Menu
        flowMenu                     matlab.ui.container.Menu
        ASCIIArcGISASCIIMenu_2       matlab.ui.container.Menu
        FlowrateMenu                 matlab.ui.container.Menu
        StudyareaflowASCIIfilegeneratedinthesamemannerasflowdataMenu  matlab.ui.container.Menu
        RiversourcecoordinatesMenu   matlab.ui.container.Menu
        demXYMenu                    matlab.ui.container.Menu
        ElevationMinMenu             matlab.ui.container.Menu
        Menu_9                       matlab.ui.container.Menu
        referenceconcavityMenu       matlab.ui.container.Menu
        Menu_10                      matlab.ui.container.Menu
        filelocationMenu             matlab.ui.container.Menu
        GeneratedriverprofilestoragelocationMenu  matlab.ui.container.Menu
    end

    
    properties (Access = private)
         lastSelectedFolder char = '' % Initialize lastSelectedFolder property 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfilelocationButton
        function Elev_read(app, event)
        % Check if the last selected folder is available
    if exist(app.lastSelectedFolder, 'dir')
        initialPath = app.lastSelectedFolder;
    else
        initialPath = pwd; % Default to current working directory
    end
    % Open a file selection dialog with default file type .txt
   [fileName, filePath] = uigetfile('*.txt', 'Select a text file', initialPath);
    
    % Check if a file is selected
    if isequal(fileName, 0)
    else
        % File is selected, update the TextArea_1 with the selected file name
        selectedFilePath = fullfile(filePath, fileName);
        app.TextArea.Value = selectedFilePath;
        
        % Store the selected folder path for future use
        app.lastSelectedFolder = filePath;
    end
        end

        % Button pushed function: SelectfilelocationButton_2
        function FlowDir_read(app, event)
          % Check if the last selected folder is available
    if exist(app.lastSelectedFolder, 'dir')
        initialPath = app.lastSelectedFolder;
    else
        initialPath = pwd; % Default to current working directory
    end
    % Open a file selection dialog with default file type .txt
   [fileName, filePath] = uigetfile('*.txt', 'Select a text file', initialPath);
    
    % Check if a file is selected
    if isequal(fileName, 0)
    else
        % File is selected, update the TextArea_2 with the selected file name
        selectedFilePath = fullfile(filePath, fileName);
        app.TextArea_2.Value = selectedFilePath;
        
        % Store the selected folder path for future use
        app.lastSelectedFolder = filePath;
    end
        end

        % Button pushed function: SelectfilelocationButton_3
        function FlowAcc_read(app, event)
  % Check if the last selected folder is available
    if exist(app.lastSelectedFolder, 'dir')
        initialPath = app.lastSelectedFolder;
    else
        initialPath = pwd; % Default to current working directory
    end
    
    % Open a file selection dialog with default file type .txt
    [fileName, filePath] = uigetfile('*.txt', 'Select a text file', initialPath);
    
    % Check if a file is selected
    if isequal(fileName, 0)
    else
        % File is selected, update the TextArea_3 with the selected file name
        selectedFilePath = fullfile(filePath, fileName);
        app.TextArea_3.Value = selectedFilePath;
        
        % Store the selected folder path for future use
        app.lastSelectedFolder = filePath;
    end
        end

        % Button pushed function: SelectfilelocationButton_4
        function Riverhead_read(app, event)
   % Check if the last selected folder is available
    if exist(app.lastSelectedFolder, 'dir')
        initialPath = app.lastSelectedFolder;
    else
        initialPath = pwd; % Default to current working directory
    end
    
    % Open a file selection dialog with default file type .txt
    [fileName, filePath] = uigetfile('*.txt', 'Select a text file', initialPath);
    
    % Check if a file is selected
    if isequal(fileName, 0)
    else
        % File is selected, update the TextArea_4 with the selected file name
        selectedFilePath = fullfile(filePath, fileName);
        app.TextArea_4.Value = selectedFilePath;
        
        % Store the selected folder path for future use
        app.lastSelectedFolder = filePath;
    end
        end

        % Button pushed function: HideHelpButton
        function ButtonCallback(app, event)
if strcmp(app.instructionPanel.Visible, 'on')
    % 隐藏 Panel
    app.instructionPanel.Visible = 'off';
    % 调整 UIFigure 的位置和大小为 Panel_2 的大小
    app.UIFigure.Position = [app.UIFigure.Position(1:2), app.BackcountrycalculationofriverelevationprofilesPanel.Position(3:4)];
    app.instructionPanel.Position = app.UIFigure.Position;
    app.HideHelpButton.Text = 'Show Help';
else
    % 显示 Panel
    app.instructionPanel.Visible = 'on';
    % 调整 UIFigure 的位置和大小为原始大小
    app.UIFigure.Position = [500, 500, 768, 327]; % 设置初始大小
    app.instructionPanel.Position = [588,0,180,327];
    app.TextArea_8.Position = [21 101 127 162];
    app.HideHelpButton.Text = 'Hide Help';
end
        end

        % Button pushed function: SelectfilelocationButton_5
        function output_location(app, event)
        % Check if the last selected folder is available
    if exist(app.lastSelectedFolder, 'dir')
        initialPath = app.lastSelectedFolder;
    else
        initialPath = pwd; % Default to current working directory
    end
    
    % Open a folder selection dialog
    selectedFolder = uigetdir(initialPath, 'Select a folder');
    
    % Check if a folder is selected
    if isequal(selectedFolder, 0)
        % User canceled folder selection
        return;
    else
        % Folder is selected, update the TextArea_3 with the selected folder path
        app.TextArea_7.Value = selectedFolder;
        
        % Store the selected folder path for future use
        app.lastSelectedFolder = selectedFolder;
    end
        end

        % Button pushed function: DefineButton
        function calculate(app, event)
              % 从文本框中获取文件地址
    filePath = app.TextArea.Value{1};
    % 读取高程文件
    fileID = fopen(filePath, 'r');
    if fileID == -1
        disp('Error: Unable to open file');
        return;end
    
    % 读取文件内容
    data = textscan(fileID, '%s', 'Delimiter', '\n');
    fclose(fileID);
    % 从第三行和第四行提取数据
    try
        thirdLine = strsplit(data{1}{3});fourthLine = strsplit(data{1}{4});fifthLine =  strsplit(data{1}{5});
        LeftX = str2double(thirdLine{2});DownY = str2double(fourthLine{2});cellsize = str2double(fifthLine{2});
    catch
        disp('Error: Unable to extract required data from file');
        return;
    end
    data = readmatrix(filePath, 'HeaderLines', 6);%删除前6行
    Elev = data;

    % 读取流向文件
    filePath = app.TextArea_2.Value{1};
    fileID = fopen(filePath, 'r');
    if fileID == -1
        disp('Error: Unable to open file');
        return;end
    data = readmatrix(filePath, 'HeaderLines', 6);%删除前6行
    FlowDir = data;

    % 读取流量文件
     filePath = app.TextArea_3.Value{1};
    fileID = fopen(filePath, 'r');
    if fileID == -1
        disp('Error: Unable to open file');
        return;end
    data = readmatrix(filePath, 'HeaderLines', 6);%删除前6行
    FlowAcc = data;

    % 读取河流源头坐标
     filePath = app.TextArea_4.Value{1};
      fileID = fopen(filePath, 'r');
    if fileID == -1
        disp('Error: Unable to open file');
        return;end
   data = load(filePath);
    fclose(fileID);
    headX = data(:, 2); % 第二列数据
    headY = data(:, 3); % 第三列数据
    %最小高程与参考凹度读取
    eleWin=str2double(app.ElevationMinTextArea.Value{1});mVSn=str2double(app.referenceconcavityTextArea.Value{1});A0=1;%无维度化汇水面积1m2

% 溯源提取河流纵剖面
[m,n]=size(Elev);lenheadX=length(headX); % RiverPro=cell(lenheadX,1);

% RasterToASCII工具中得到的txt的左下角坐标，但行列号开始的是左上
upY=DownY+m*cellsize; 
for j=1:lenheadX
    midRow=floor((abs(upY-headY(j)))/cellsize)+1; midCol=floor((abs(headX(j)-LeftX))/cellsize)+1;% start point 
    disp(midCol);
    RiverLen=0; RiverPro1=[]; kk=1; 
    % 首先填充顺流矩阵 RiverPro1
    RiverPro1=[RiverPro1;midRow midCol RiverLen Elev(midRow,midCol) FlowDir(midRow,midCol) FlowAcc(midRow,midCol)]; 
    
    while (midRow>1 && midRow<m && midCol>1 && midCol<n )  % the searching point is within the matrix 
        if Elev(midRow,midCol)<0 ;break; end % elevation is positive
        switch FlowDir(midRow,midCol)
            case 1 % East
                midRow=midRow; midCol=midCol+1;RiverLen=RiverLen+1;
            case 2 % SE
                midRow=midRow+1; midCol=midCol+1; RiverLen=RiverLen+1.414;
            case 4 % South
                midRow=midRow+1; midCol=midCol; RiverLen=RiverLen+1;
            case 8 % SW
                midRow=midRow+1; midCol=midCol-1; RiverLen=RiverLen+1.414;
            case 16 % Weat
                midRow=midRow; midCol=midCol-1; RiverLen=RiverLen+1;
            case 32 % NW
                midRow=midRow-1; midCol=midCol-1; RiverLen=RiverLen+1.414;
            case 64 % North
                midRow=midRow-1; midCol=midCol; RiverLen=RiverLen+1;
            case 128 % NE
                midRow=midRow-1; midCol=midCol+1; RiverLen=RiverLen+1.414;    
        end
        if Elev(midRow,midCol)<0 ;break; end  % elevation is positive
        if (RiverPro1(kk,4)-Elev(midRow,midCol)>=eleWin)  % beyond the elevation window
            RiverPro1=[RiverPro1;midRow midCol RiverLen Elev(midRow,midCol) FlowDir(midRow,midCol) FlowAcc(midRow,midCol)];
            kk=kk+1;
        end
    end
    % 溯流矩阵 upstream distance 
    jRiverPro=zeros(kk,7); minEle=RiverPro1(kk,4);MaxRiverLen=RiverPro1(kk,3);
    for i=1:kk
        jRiverPro(i,1)=RiverPro1(kk-i+1,1); % Row Number
        %jRiverPro(i,1)=upY-cellsize.*jRiverPro(i,1); %实际的地理坐标Y  jRiverPro(i,1)=upY-cellsize.*RiverPro{j,1}(i,1)
        jRiverPro(i,2)=RiverPro1(kk-i+1,2); % Col Number
        %jRiverPro(i,2)=LeftX+cellsize.*jRiverPro(i,2); %实际的地理坐标X  jRiverPro(i,2)=LeftX+cellsize.*RiverPro{j,1}(i,2)
        
        jRiverPro(i,3)=(MaxRiverLen-RiverPro1(kk-i+1,3)); % river length
        jRiverPro(i,4)=RiverPro1(kk-i+1,4); % elevation
        jRiverPro(i,5)=RiverPro1(kk-i+1,5); % Flow Direction
        jRiverPro(i,6)=(RiverPro1(kk-i+1,6)); % drainage area  
    end
    
    jRiverPro(:,1)=upY-cellsize.*jRiverPro(:,1);
    jRiverPro(:,2)=LeftX+cellsize.*jRiverPro(:,2);
    jRiverPro(:,3)=jRiverPro(:,3).*cellsize; % river length
    jRiverPro(:,4)=jRiverPro(:,4); % -minEle; % elevation
    jRiverPro(:,6)=jRiverPro(:,6).*(cellsize^2);
    
    %计算Chi距离 (Perron and Royden,2012)
    for i=2:kk
        jRiverPro(i,7)=jRiverPro(i-1,7)+((A0./jRiverPro(i-1,6)).^mVSn).*(jRiverPro(i,3)-jRiverPro(i-1,3));
    end
   
    % 存储jRiverPro 到 D:\ErosionQilian\MatlabWork\basin_yumu\result_yumushan
    filePath = app.TextArea_7.Value{1};
    str1=filePath;
    str2='\RiverPro';str3=num2str(j);str4='.txt';
    str_j=strcat(str1,str2,str3,str4);
    fid=fopen(str_j,'w');
    for i=1:kk
        for k=1:6
            fprintf(fid,'%f ',jRiverPro(i,k));
        end
        fprintf(fid,'%f\n',jRiverPro(i,7));
    end
    fclose(fid);
    jRiverPro=[];
end
msg = '运算已结束';title = '提示';
       msgbox(msg, title);
        end

        % Value changed function: TextArea_8
        function TextAreaClicked(app, event)
             if strcmp(app.instructionPanel.Visible, 'on')
        % 根据点击的标签来显示相应的内容
        switch label
            case 'TextArea'
               app.TextArea_8.Value =  {'    （单击输入框可获取数据详细说明）'};
            case 'Label2'
                app.TextArea_8.Value = '这是 Label2 的内容';
            case 'Label3'
                app.TextArea_8.Value = '这是 Label3 的内容';
        end
             end
        end

        % Value changed function: TextArea
        function TextAreaPushed(app, event)
    % 调用统一的函数来处理标签点击事件并显示相应内容
    TextAreaClicked(app, 'TextArea');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.instructionPanel = uipanel(app.UIFigure);
            app.instructionPanel.Title = 'instruction';
            app.TextArea_8 = uitextarea(app.instructionPanel);
            app.TextArea_8.ValueChangedFcn = createCallbackFcn(app, @TextAreaClicked, true);
            app.TextArea_8.Editable = 'off';
            app.TextArea_8.Value = {'  A program to calculate river elevation profiles retrospectively, whose precomputation needs to be done in GIS software. (Right-click for a detailed description of the data)'};
            app.BackcountrycalculationofriverelevationprofilesPanel = uipanel(app.UIFigure);
            app.BackcountrycalculationofriverelevationprofilesPanel.Title = 'Backcountry calculation of river elevation profiles';
            app.FlowrateLabel = uilabel(app.BackcountrycalculationofriverelevationprofilesPanel);
            app.FlowrateLabel.Text = 'Flow rate';
            app.RiversourcecoordinatesLabel = uilabel(app.BackcountrycalculationofriverelevationprofilesPanel);
            app.RiversourcecoordinatesLabel.Text = 'River source coordinates';
            app.ElevationMinTextAreaLabel = uilabel(app.BackcountrycalculationofriverelevationprofilesPanel);
            app.ElevationMinTextAreaLabel.Text = 'Elevation Min';
            app.ElevationMinTextArea = uitextarea(app.BackcountrycalculationofriverelevationprofilesPanel);
            app.ElevationMinTextArea.Value = {'0.0001'};
            app.TextArea_3 = uitextarea(app.BackcountrycalculationofriverelevationprofilesPanel);
            app.TextArea_4 = uitextarea(app.BackcountrycalculationofriverelevationprofilesPanel);
            app.referenceconcavityTextAreaLabel = uilabel(app.BackcountrycalculationofriverelevationprofilesPanel);
            app.referenceconcavityTextAreaLabel.Text = 'reference concavity ';
            app.referenceconcavityTextArea = uitextarea(app.BackcountrycalculationofriverelevationprofilesPanel);
            app.referenceconcavityTextArea.Value = {'0.45'};
            app.CancelButton = uibutton(app.BackcountrycalculationofriverelevationprofilesPanel, 'push');
            app.CancelButton.Text = 'Cancel';
            app.HideHelpButton = uibutton(app.BackcountrycalculationofriverelevationprofilesPanel, 'push');
            app.HideHelpButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonCallback, true);
            app.HideHelpButton.Text = 'Hide Help';
            app.ElevationLabel = uilabel(app.BackcountrycalculationofriverelevationprofilesPanel);
            app.ElevationLabel.Text = 'Elevation';
            app.TextArea = uitextarea(app.BackcountrycalculationofriverelevationprofilesPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaPushed, true);
            app.SelectfilelocationButton = uibutton(app.BackcountrycalculationofriverelevationprofilesPanel, 'push');
            app.SelectfilelocationButton.ButtonPushedFcn = createCallbackFcn(app, @Elev_read, true);
            app.SelectfilelocationButton.Text = 'Select file location';
            app.FlowLabel = uilabel(app.BackcountrycalculationofriverelevationprofilesPanel);
            app.FlowLabel.Text = 'Flow';
            app.TextArea_2 = uitextarea(app.BackcountrycalculationofriverelevationprofilesPanel);
            app.SelectfilelocationButton_2 = uibutton(app.BackcountrycalculationofriverelevationprofilesPanel, 'push');
            app.SelectfilelocationButton_2.ButtonPushedFcn = createCallbackFcn(app, @FlowDir_read, true);
            app.SelectfilelocationButton_2.Text = 'Select file location';
            app.SelectfilelocationButton_3 = uibutton(app.BackcountrycalculationofriverelevationprofilesPanel, 'push');
            app.SelectfilelocationButton_3.ButtonPushedFcn = createCallbackFcn(app, @FlowAcc_read, true);
            app.SelectfilelocationButton_3.Text = 'Select file location';
            app.SelectfilelocationButton_4 = uibutton(app.BackcountrycalculationofriverelevationprofilesPanel, 'push');
            app.SelectfilelocationButton_4.ButtonPushedFcn = createCallbackFcn(app, @Riverhead_read, true);
            app.SelectfilelocationButton_4.Text = 'Select file location';
            app.TextArea_7 = uitextarea(app.BackcountrycalculationofriverelevationprofilesPanel);
            app.SelectfilelocationButton_5 = uibutton(app.BackcountrycalculationofriverelevationprofilesPanel, 'push');
            app.SelectfilelocationButton_5.ButtonPushedFcn = createCallbackFcn(app, @output_location, true);
            app.SelectfilelocationButton_5.Text = 'Select file location';
            app.StoragelocationLabel = uilabel(app.BackcountrycalculationofriverelevationprofilesPanel);
            app.StoragelocationLabel.Text = 'Storage location';
            app.DefineButton = uibutton(app.BackcountrycalculationofriverelevationprofilesPanel, 'push');
            app.DefineButton.ButtonPushedFcn = createCallbackFcn(app, @calculate, true);
            app.DefineButton.Text = 'Define';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.elevationMenu = uimenu(app.ContextMenu);
            app.elevationMenu.Text = 'elevation';
            app.ASCIIArcGISASCIIMenu = uimenu(app.elevationMenu);
            app.ASCIIArcGISASCIIMenu.Separator = 'on';
            app.ASCIIArcGISASCIIMenu.Text = '研究区高程ASCII文件，可以利用ArcGIS软件里栅格转ASCII生成。';
            app.flowMenu = uimenu(app.ContextMenu);
            app.flowMenu.Text = 'flow';
            app.ASCIIArcGISASCIIMenu_2 = uimenu(app.flowMenu);
            app.ASCIIArcGISASCIIMenu_2.Text = 'The study area flow direction ASCII file can be generated using the ArcGIS software hydrologic analysis module to generate a flow direction raster, and then using the raster to ASCII to generate it.';
            app.FlowrateMenu = uimenu(app.ContextMenu);
            app.FlowrateMenu.Text = 'Flow rate';
            app.StudyareaflowASCIIfilegeneratedinthesamemannerasflowdataMenu = uimenu(app.FlowrateMenu);
            app.StudyareaflowASCIIfilegeneratedinthesamemannerasflowdataMenu.Text = 'Study area flow ASCII file, generated in the same manner as flow data';
            app.RiversourcecoordinatesMenu = uimenu(app.ContextMenu);
            app.RiversourcecoordinatesMenu.Text = 'River source coordinates';
            app.demXYMenu = uimenu(app.RiversourcecoordinatesMenu);
            app.demXYMenu.Text = 'Record the coordinates at the dem data or at the source of the river as determined using the algorithm. The file should have three columns, river number, X data and Y data';
            app.ElevationMinMenu = uimenu(app.ContextMenu);
            app.ElevationMinMenu.Text = 'Elevation Min';
            app.Menu_9 = uimenu(app.ElevationMinMenu);
            app.Menu_9.Text = 'Minimum value of river elevation selected, default value is 0.00001 if not required';
            app.referenceconcavityMenu = uimenu(app.ContextMenu);
            app.referenceconcavityMenu.Text = 'reference concavity ';
            app.Menu_10 = uimenu(app.referenceconcavityMenu);
            app.Menu_10.Text = 'A reference concavity of 0.45 is generally chosen, or a regional reference concavity is calculated';
            app.filelocationMenu = uimenu(app.ContextMenu);
            app.filelocationMenu.Text = 'file location';
            app.GeneratedriverprofilestoragelocationMenu = uimenu(app.filelocationMenu);
            app.GeneratedriverprofilestoragelocationMenu.Text = 'Generated river profile storage location';
            % Assign app.ContextMenu
            app.instructionPanel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RiverProInfo_en

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end